classdef DipoleAntennaDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        DesignTab                   matlab.ui.container.Tab
        DipoleAntennaDesignerLabel  matlab.ui.control.Label
        GenerateButton              matlab.ui.control.Button
        ALabel                      matlab.ui.control.Label
        mLabel_2                    matlab.ui.control.Label
        mLabel                      matlab.ui.control.Label
        HzLabel                     matlab.ui.control.Label
        ImEditField                 matlab.ui.control.NumericEditField
        ImEditFieldLabel            matlab.ui.control.Label
        WidthEditField              matlab.ui.control.NumericEditField
        WidthEditFieldLabel         matlab.ui.control.Label
        LengthEditField             matlab.ui.control.NumericEditField
        LengthEditFieldLabel        matlab.ui.control.Label
        FrequencyEditField          matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel     matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        AnalysisITab                matlab.ui.container.Tab
        DRadiationButton            matlab.ui.control.Button
        CurrentDistrubutionButton   matlab.ui.control.Button
        ImpedanceButton             matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        AnalysisIITab               matlab.ui.container.Tab
        ElevationButton             matlab.ui.control.Button
        AzimuthalButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
         % Description
         c = 299792458;
         n = 377;
         y = 0.57721566;
         Pax %Polaraxes object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Pax = polaraxes(app.AnalysisIITab);
            app.Pax.OuterPosition = [0.1 0.25 0.75 0.75];
            app.UIAxes2.NextPlot = "replaceall";
            app.FrequencyEditField.Value = 1e8;
            app.LengthEditField.Value = 1.437;
            app.WidthEditField.Value = 0.05;
            app.ImEditField.Value = 1;
        end

        % Button pushed function: ImpedanceButton
        function ImpedanceButtonPushed(app, event)
            fc = app.FrequencyEditField.Value; % Get frequency from the input field
            l = app.LengthEditField.Value;
            w = app.WidthEditField.Value;
            Im = app.ImEditField.Value;
    
            % Perform calculations
            a = w / 4;
            app.c = 299792458;
            app.n = 377;
            app.y = 0.57721566;


            f = (9 * fc) / 10: fc / 100: 11 * fc / 10;
            kL = (2 * pi * l / app.c) .* f;
    
            p = (app.n ./ (2 * pi * (sin(kL ./ 2)).^2));
    
            Rp1 = app.y + log(kL) - cosint(kL);
            Rp2 = ((0.5) .* sin(kL) .* (sinint(2 * kL) - (2 * sinint(kL))));
            Rp3 = ((0.5) .* cos(kL) .* (cosint(2 * kL) - (2 * cosint(kL)) + app.y + log((0.5) .* kL)));
            R = p .* (Rp1 + Rp2 + Rp3);
    
            Xp1 = sinint(kL);
            Xp2 = (0.5) .* cos(kL) .* (sinint(2 * kL) - (2 * sinint(kL))) .* (-1);
            Xp3 = (0.5) .* sin(kL) .* (cosint(2 * (kL)) - (2 * cosint(kL)) + cosint(2 * ((a / l)^2)) .* kL);
            X = p .* (Xp1 + Xp2 + Xp3);
            
            grid(app.UIAxes2,"on");
            axis(app.UIAxes2,"on");
            colorbar(app.UIAxes2,"off");
            view(app.UIAxes2,2);
            plot(app.UIAxes2, f, R, 'b', 'DisplayName', 'Resistance (R)');
            title(app.UIAxes2, 'Impedance');
            xlabel(app.UIAxes2, 'Frequency (Hz)');
            ylabel(app.UIAxes2, 'Impedance (ohm)');
            
            % Hold the current plot
            hold(app.UIAxes2,'on');
    
            % Plot Reactance (X) in red
            plot(app.UIAxes2, f, X, 'r', 'DisplayName', 'Reactance (X)');
            
            % Release the hold
            hold(app.UIAxes2,'off');
    
            % Add a legend to distinguish the two plots
            legend(app.UIAxes2);
        end

        % Button pushed function: CurrentDistrubutionButton
        function CurrentDistrubutionButtonPushed(app, event)
            fc = app.FrequencyEditField.Value; % Get frequency from the input field
            l = app.LengthEditField.Value;
            w = app.WidthEditField.Value;
            Im = app.ImEditField.Value;

            app.c = 299792458;
            app.n = 377;
            app.y = 0.57721566;

            lamda = app.c/fc;
            % Calculate Iz vs. z
            z = -l/2:l/1000:l/2;
            Iz = zeros(length(z));
            b = (2*pi)/lamda;

            for i = 1:length(z)
                if(z(i)<0)
                    Iz(i) = Im*sin(b*((l/2)+z(i)));
                else
                    Iz(i) = Im*sin(b*((l/2)-z(i)));
                end
            end
            
            % Plot Iz vs. z in UIAxes2
            grid(app.UIAxes2,"on");
            axis(app.UIAxes2,"on");
            legend(app.UIAxes2,'off');
            colorbar(app.UIAxes2,"off");
            plot(app.UIAxes2, z, Iz);
            view(app.UIAxes2,2);
            title(app.UIAxes2, 'Current Distribution (Iz vs. z)');
            xlabel(app.UIAxes2, 'z(m)');
            ylabel(app.UIAxes2, 'Iz(A)');
        end

        % Button pushed function: DRadiationButton
        function DRadiationButtonPushed(app, event)
            fc = app.FrequencyEditField.Value;
            l = app.LengthEditField.Value;
            w = app.WidthEditField.Value;
            Im = app.ImEditField.Value;
            lamda = app.c/fc;

            theta = 0:0.025:2*pi;
            phi = 0:0.025:2*pi;

            l_lamda1 = l/lamda;

            % Handling the case when theta is exactly equal to (pi/2)
            epsilon = 1e-10;
            theta(theta == (pi/2)) = theta(theta == (pi/2)) + epsilon;

            U1 = ((cos(l_lamda1*pi*cos(theta-(pi/2))) - cos(l_lamda1*pi))./sin(theta-(pi/2))).^2;

            U1_1 = 10*log10(U1 + epsilon);

            min1 = min(U1_1);
            U = U1_1 - min1;

            U(1,1) = 0;

            for i = 1:length(phi)
                theta(i, :) = theta(1, :);
            end

            phi = phi';
            for m = 1:length(phi)
                phi(:, m) = phi(:, 1);
            end

            for k = 1:length(U)
                U(k, :) = U(1, :);
            end

            [k, l, m] = sph2cart(phi, theta, U);

            view(app.UIAxes2,3);
            
            grid(app.UIAxes2,"off");
            %axis(app.UIAxes2,"off");
            C=sqrt(k.^2+l.^2+m.^2);
            surf(app.UIAxes2, k, l, m,C,EdgeColor="none");
            colormap(app.UIAxes2, 'jet');
            o = colorbar(app.UIAxes2,"east");
            o.Label.String = "dBi";
            o.Label.HorizontalAlignment = "right";
            title(app.UIAxes2, 'Radiation Power Pattern for Dipole Antenna');
            xlabel(app.UIAxes2, 'x');
            ylabel(app.UIAxes2, 'y');
            zlabel(app.UIAxes2, 'z');
            shading(app.UIAxes2,"interp");


        end

        % Button pushed function: AzimuthalButton
        function AzimuthalButtonPushed(app, event)
            fc = app.FrequencyEditField.Value;
            l = app.LengthEditField.Value;
            w = app.WidthEditField.Value;
            Im = app.ImEditField.Value;
            lamda = app.c/fc;

            theta = 0:0.05:2*pi;
            phi = 0:0.05:2*pi;


            % Handling the case when theta is exactly equal to (pi/2)
            epsilon = 1e-10;
            theta(theta == (pi/2)) = theta(theta == (pi/2)) + epsilon;
            
            l_lamda1=l/lamda;% length of antenna in terms of wavelengths 
            
            % evaluating radiation intensity(U)
            U1=(( cos(l_lamda1*pi*cos(theta-(pi/2))) - cos(l_lamda1*pi) )./ sin(theta-(pi/2)) ).^2 ;
            
            %converting to dB scale
            U1_1=10*log10(U1); % add a very small number for l = even multiples of lamda.
            
            %normalizing in order to make U vector positive
            min1=min(U1_1);
            U=U1_1-min1;

            polarplot(app.Pax, phi, zeros(length(phi))+U(1,1));
            title(app.Pax, 'Azimuthal Plot (in dBi)',FontSize=15);

        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)

            l = app.LengthEditField.Value;
            w = app.WidthEditField.Value;
            
            L = l;  % Length of the dipole (in meters)
            W = w; % Width of the dipole (in meters)
            
            % Create the dipole strip antenna structure
            k = [-W/2, -W/2; W/2, W/2];
            z = [-L/2, L/2; -L/2, L/2];
            x = zeros(size(k));
            
            mesh(app.UIAxes,x, k, z, 'EdgeColor', 'k', 'FaceColor', [0.9290 0.6940 0.1250]);
            
            % Set plot properties
            title(app.UIAxes,'Dipole Strip Antenna Structure');
            ylabel(app.UIAxes,'y(m)');
            zlabel(app.UIAxes,'z(m)');
            axis(app.UIAxes,'equal');
            axis(app.UIAxes,'vis3d');
            xlim(app.UIAxes,[-0.001, 0.001]);
            
            
        end

        % Button pushed function: ElevationButton
        function ElevationButtonPushed(app, event)
            fc = app.FrequencyEditField.Value;
            l = app.LengthEditField.Value;
            w = app.WidthEditField.Value;
            Im = app.ImEditField.Value;
            lamda = app.c/fc;

            theta = 0:0.05:2*pi;
            phi = 0:0.05:2*pi;

           
            % Handling the case when theta is exactly equal to (pi/2)
            epsilon = 1e-10;
            theta(theta == (pi/2)) = theta(theta == (pi/2)) + epsilon;
            
            l_lamda1=l/lamda;% length of antenna in terms of wavelengths 
            
            % evaluating radiation intensity(U)
            U1=(( cos(l_lamda1*pi*cos(theta-(pi/2))) - cos(l_lamda1*pi) )./ sin(theta-(pi/2)) ).^2 ;
            
            %converting to dB scale
            U1_1=10*log10(U1); % add a very small number for l = even multiples of lamda.
            
            %normalizing in order to make U vector positive
            min1=min(U1_1);
            U=U1_1-min1;
            
            polarplot(app.Pax, theta, U);
            title(app.Pax, 'Elevation Plot (in dBi)',FontSize=15);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DesignTab = uitab(app.TabGroup);
            app.DesignTab.Title = 'Design';
            app.UIAxes = uiaxes(app.DesignTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FrequencyEditFieldLabel = uilabel(app.DesignTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.DesignTab, 'numeric');
            app.LengthEditFieldLabel = uilabel(app.DesignTab);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.DesignTab, 'numeric');
            app.WidthEditFieldLabel = uilabel(app.DesignTab);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.DesignTab, 'numeric');
            app.ImEditFieldLabel = uilabel(app.DesignTab);
            app.ImEditFieldLabel.Text = 'Im';
            app.ImEditField = uieditfield(app.DesignTab, 'numeric');
            app.HzLabel = uilabel(app.DesignTab);
            app.HzLabel.Text = 'Hz';
            app.mLabel = uilabel(app.DesignTab);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.DesignTab);
            app.mLabel_2.Text = 'm';
            app.ALabel = uilabel(app.DesignTab);
            app.ALabel.Text = 'A';
            app.GenerateButton = uibutton(app.DesignTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.DipoleAntennaDesignerLabel = uilabel(app.DesignTab);
            app.DipoleAntennaDesignerLabel.Text = 'Dipole Antenna Designer';
            app.AnalysisITab = uitab(app.TabGroup);
            app.AnalysisITab.Title = 'Analysis-I';
            app.UIAxes2 = uiaxes(app.AnalysisITab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ImpedanceButton = uibutton(app.AnalysisITab, 'push');
            app.ImpedanceButton.ButtonPushedFcn = createCallbackFcn(app, @ImpedanceButtonPushed, true);
            app.ImpedanceButton.Text = 'Impedance';
            app.CurrentDistrubutionButton = uibutton(app.AnalysisITab, 'push');
            app.CurrentDistrubutionButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentDistrubutionButtonPushed, true);
            app.CurrentDistrubutionButton.Text = 'Current Distrubution';
            app.DRadiationButton = uibutton(app.AnalysisITab, 'push');
            app.DRadiationButton.ButtonPushedFcn = createCallbackFcn(app, @DRadiationButtonPushed, true);
            app.DRadiationButton.Text = '3D Radiation';
            app.AnalysisIITab = uitab(app.TabGroup);
            app.AnalysisIITab.Title = 'Analysis-II';
            app.AzimuthalButton = uibutton(app.AnalysisIITab, 'push');
            app.AzimuthalButton.ButtonPushedFcn = createCallbackFcn(app, @AzimuthalButtonPushed, true);
            app.AzimuthalButton.Text = 'Azimuthal';
            app.ElevationButton = uibutton(app.AnalysisIITab, 'push');
            app.ElevationButton.ButtonPushedFcn = createCallbackFcn(app, @ElevationButtonPushed, true);
            app.ElevationButton.Text = 'Elevation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DipoleAntennaDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end